classdef auidoEffectBoxUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TabGroup                    matlab.ui.container.TabGroup
        AudioEffectBoxTab           matlab.ui.container.Tab
        Effect3DropDown             matlab.ui.control.DropDown
        Effect3DropDownLabel        matlab.ui.control.Label
        Effect2DropDown             matlab.ui.control.DropDown
        Effect2DropDownLabel        matlab.ui.control.Label
        Effect1DropDown             matlab.ui.control.DropDown
        Effect1DropDownLabel        matlab.ui.control.Label
        DistortionSwitch            matlab.ui.control.ToggleSwitch
        DistortionSwitchLabel       matlab.ui.control.Label
        TimestrechKnob              matlab.ui.control.Knob
        TimestrechLabel             matlab.ui.control.Label
        PitchdistortionSlider       matlab.ui.control.Slider
        PitchdistortionSliderLabel  matlab.ui.control.Label
        ReverbSwitch                matlab.ui.control.ToggleSwitch
        ReverbSwitchLabel           matlab.ui.control.Label
        PredelaymsKnob              matlab.ui.control.Knob
        PredelaymsKnobLabel         matlab.ui.control.Label
        DiffuseSlider               matlab.ui.control.Slider
        DiffuseSliderLabel          matlab.ui.control.Label
        DryWetKnob                  matlab.ui.control.Knob
        DryWetKnobLabel             matlab.ui.control.Label
        TypeKnob                    matlab.ui.control.DiscreteKnob
        TypeKnobLabel               matlab.ui.control.Label
        generateButton              matlab.ui.control.Button
        choosefileButton            matlab.ui.control.Button
        EQSwitch                    matlab.ui.control.ToggleSwitch
        EQSwitchLabel               matlab.ui.control.Label
        cutoffHzKnob                matlab.ui.control.Knob
        cutoffHzKnobLabel           matlab.ui.control.Label
        gaindBSlider                matlab.ui.control.Slider
        gaindBSliderLabel           matlab.ui.control.Label
        UIAxes                      matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: generateButton
        function generateButtonPushed(app, event)
            [input, path] = uigetfile('*.wav');
            [in, fs] = audioread(input);
            in = in(:, 1);
            out = in;
            
            t = 1/fs : 1/fs : length(out)/fs;
            plot(app.UIAxes, t, out, '-b');
            hold(app.UIAxes);
            
            switch app.Effect1DropDown.Value
                case 'EQ'
                    switch app.EQSwitch.Value
                        case 'On'
                            type = 't';
                            if app.TypeKnob.Value=="Bass"
                                type = 'b';
                            end
                            [b,a] = shelving(fs, double(app.gaindBSlider.Value), double(app.cutoffHzKnob.Value), type);
                            out = filter(b,a,in);
                            out = out./max(abs(out));
                        case 'Off'  
                    end
                case 'Reverb'
                    switch app.ReverbSwitch.Value
                        case 'On'
                            pre_delay_amount = floor((app.PredelaymsKnob.Value)*(441/10));
                            pre_delay_gain = 0.3;
                            comb_delays = [1759, 1949, 2113, 2293, 2467, 2647];
                            comb_gain = app.DiffuseSlider.Value;
                            ap_delay = 307;
                            ap_gain = 0.3;
                            
                            direct_gain = 1 - app.DryWetKnob.Value;
                            er_gain = app.DryWetKnob.Value*0.2;
                            reverb_gain = app.DryWetKnob.Value*0.8;
                            
                            dbs = 0;
                            out = reverb(out, fs, pre_delay_amount, pre_delay_gain, comb_delays, comb_gain, ap_delay, ap_gain, reverb_gain, er_gain, direct_gain, dbs);
                            out = out ./ max(abs(out));
                    end
                case 'Distortion'
                    switch app.DistortionSwitch.Value
                        case 'On'
                            out = P_combine(out, fs, 400,400,floor(app.TimestrechKnob.Value/100),floor(app.PitchdistortionSlider.Value));
                            out = out./max(abs(out));
                    end
            end
            
            switch app.Effect2DropDown.Value
                case 'EQ'
                    switch app.EQSwitch.Value
                        case 'On'
                            type = 't';
                            if app.TypeKnob.Value=="Bass"
                                type = 'b';
                            end
                            [b,a] = shelving(fs, double(app.gaindBSlider.Value), double(app.cutoffHzKnob.Value), type);
                            out = filter(b,a,out);
                            out = out./max(abs(out));
                        case 'Off'  
                    end
                case 'Reverb'
                    switch app.ReverbSwitch.Value
                        case 'On'
                            pre_delay_amount = floor((app.PredelaymsKnob.Value)*(441/10));
                            pre_delay_gain = 0.3;
                            comb_delays = [1759, 1949, 2113, 2293, 2467, 2647];
                            comb_gain = app.DiffuseSlider.Value;
                            ap_delay = 307;
                            ap_gain = 0.3;
                            
                            direct_gain = 1 - app.DryWetKnob.Value;
                            er_gain = app.DryWetKnob.Value*0.2;
                            reverb_gain = app.DryWetKnob.Value*0.8;
                            
                            dbs = 0;
                            out = reverb(out, fs, pre_delay_amount, pre_delay_gain, comb_delays, comb_gain, ap_delay, ap_gain, reverb_gain, er_gain, direct_gain, dbs);
                            out = out ./ max(abs(out));
                    end
                case 'Distortion'
                    switch app.DistortionSwitch.Value
                        case 'On'
                            out = P_combine(out, fs, 400,400,floor(app.TimestrechKnob.Value/100),floor(app.PitchdistortionSlider.Value));
                            out = out./max(abs(out));
                            
                    end
            end
            
            switch app.Effect3DropDown.Value
                case 'EQ'
                    switch app.EQSwitch.Value
                        case 'On'
                            type = 't';
                            if app.TypeKnob.Value=="Bass"
                                type = 'b';
                            end
                            [b,a] = shelving(fs, double(app.gaindBSlider.Value), double(app.cutoffHzKnob.Value), type);
                            out = filter(b,a,out);
                            out = out./max(abs(out(:)));
                        case 'Off'  
                    end
                case 'Reverb'
                    switch app.ReverbSwitch.Value
                        case 'On'
                            pre_delay_amount = floor((app.PredelaymsKnob.Value)*(441/10));
                            pre_delay_gain = 0.3;
                            comb_delays = [1759, 1949, 2113, 2293, 2467, 2647];
                            comb_gain = app.DiffuseSlider.Value;
                            ap_delay = 307;
                            ap_gain = 0.3;
                            
                            direct_gain = 1 - app.DryWetKnob.Value;
                            er_gain = app.DryWetKnob.Value*0.2;
                            reverb_gain = app.DryWetKnob.Value*0.8;
                            
                            dbs = 0;
                            out = reverb(out, fs, pre_delay_amount, pre_delay_gain, comb_delays, comb_gain, ap_delay, ap_gain, reverb_gain, er_gain, direct_gain, dbs);
                            out = out ./ max(abs(out));
                    end
                case 'Distortion'
%                     switch app.DistortionSwitch.Value
%                         case 'On'
%                             out = P_combine(out, fs, 400,400,floor(app.TimestrechKnob.Value/100),floor(app.PitchdistortionSlider.Value));
%                             out = out./max(abs(out));
%                             
%                     end
            end

            %out = 2*(out - mean(out)) ./ range(out);
            sound(out, fs);
            %plot(app.UIAxes, t, out, '-y');
            
        end

        % Value changed function: EQSwitch
        function EQSwitchValueChanged(app, event)
            value = app.EQSwitch.Value;
            
        end

        % Button pushed function: choosefileButton
        function choosefileButtonPushed(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.AudioEffectBoxTab = uitab(app.TabGroup);
            app.AudioEffectBoxTab.Title = 'Audio Effect Box';
            app.UIAxes = uiaxes(app.AudioEffectBoxTab);
            title(app.UIAxes, 'Signal Waveform')
            xlabel(app.UIAxes, 't')
            ylabel(app.UIAxes, 'A')
            app.gaindBSliderLabel = uilabel(app.AudioEffectBoxTab);
            app.gaindBSliderLabel.Text = 'gain (dB)';
            app.gaindBSlider = uislider(app.AudioEffectBoxTab);
            app.gaindBSlider.Limits = [-30 30];
            app.gaindBSlider.Value = -20;
            app.cutoffHzKnobLabel = uilabel(app.AudioEffectBoxTab);
            app.cutoffHzKnobLabel.Text = 'cutoff (Hz)';
            app.cutoffHzKnob = uiknob(app.AudioEffectBoxTab, 'continuous');
            app.cutoffHzKnob.Limits = [500 5000];
            app.cutoffHzKnob.Value = 1000;
            app.EQSwitchLabel = uilabel(app.AudioEffectBoxTab);
            app.EQSwitchLabel.Text = 'EQ';
            app.EQSwitch = uiswitch(app.AudioEffectBoxTab, 'toggle');
            app.EQSwitch.ValueChangedFcn = createCallbackFcn(app, @EQSwitchValueChanged, true);
            app.choosefileButton = uibutton(app.AudioEffectBoxTab, 'push');
            app.choosefileButton.ButtonPushedFcn = createCallbackFcn(app, @choosefileButtonPushed, true);
            app.choosefileButton.Text = 'choose file';
            app.generateButton = uibutton(app.AudioEffectBoxTab, 'push');
            app.generateButton.ButtonPushedFcn = createCallbackFcn(app, @generateButtonPushed, true);
            app.generateButton.Text = 'generate';
            app.TypeKnobLabel = uilabel(app.AudioEffectBoxTab);
            app.TypeKnobLabel.Text = 'Type';
            app.TypeKnob = uiknob(app.AudioEffectBoxTab, 'discrete');
            app.TypeKnob.Items = {'Bass', 'Treble'};
            app.TypeKnob.Value = 'Bass';
            app.DryWetKnobLabel = uilabel(app.AudioEffectBoxTab);
            app.DryWetKnobLabel.Text = 'Dry-Wet';
            app.DryWetKnob = uiknob(app.AudioEffectBoxTab, 'continuous');
            app.DryWetKnob.Limits = [0 1];
            app.DryWetKnob.Value = 0.5;
            app.DiffuseSliderLabel = uilabel(app.AudioEffectBoxTab);
            app.DiffuseSliderLabel.Text = 'Diffuse';
            app.DiffuseSlider = uislider(app.AudioEffectBoxTab);
            app.DiffuseSlider.Limits = [0 1];
            app.DiffuseSlider.Value = 0.6;
            app.PredelaymsKnobLabel = uilabel(app.AudioEffectBoxTab);
            app.PredelaymsKnobLabel.Text = 'Pre-delay (ms)';
            app.PredelaymsKnob = uiknob(app.AudioEffectBoxTab, 'continuous');
            app.PredelaymsKnob.Limits = [0 80];
            app.PredelaymsKnob.Value = 80;
            app.ReverbSwitchLabel = uilabel(app.AudioEffectBoxTab);
            app.ReverbSwitchLabel.Text = 'Reverb';
            app.ReverbSwitch = uiswitch(app.AudioEffectBoxTab, 'toggle');
            app.PitchdistortionSliderLabel = uilabel(app.AudioEffectBoxTab);
            app.PitchdistortionSliderLabel.Text = 'Pitch distortion';
            app.PitchdistortionSlider = uislider(app.AudioEffectBoxTab);
            app.PitchdistortionSlider.Limits = [1 8];
            app.PitchdistortionSlider.Value = 4;
            app.TimestrechLabel = uilabel(app.AudioEffectBoxTab);
            app.TimestrechLabel.Text = 'Time-strech (%)';
            app.TimestrechKnob = uiknob(app.AudioEffectBoxTab, 'continuous');
            app.TimestrechKnob.Limits = [100 800];
            app.TimestrechKnob.Value = 100;
            app.DistortionSwitchLabel = uilabel(app.AudioEffectBoxTab);
            app.DistortionSwitchLabel.Text = 'Distortion';
            app.DistortionSwitch = uiswitch(app.AudioEffectBoxTab, 'toggle');
            app.Effect1DropDownLabel = uilabel(app.AudioEffectBoxTab);
            app.Effect1DropDownLabel.Text = 'Effect #1';
            app.Effect1DropDown = uidropdown(app.AudioEffectBoxTab);
            app.Effect1DropDown.Items = {'EQ', 'Reverb', 'Distortion'};
            app.Effect1DropDown.Value = 'EQ';
            app.Effect2DropDownLabel = uilabel(app.AudioEffectBoxTab);
            app.Effect2DropDownLabel.Text = 'Effect #2';
            app.Effect2DropDown = uidropdown(app.AudioEffectBoxTab);
            app.Effect2DropDown.Items = {'EQ', 'Reverb', 'Distortion'};
            app.Effect2DropDown.Value = 'Reverb';
            app.Effect3DropDownLabel = uilabel(app.AudioEffectBoxTab);
            app.Effect3DropDownLabel.Text = 'Effect #3';
            app.Effect3DropDown = uidropdown(app.AudioEffectBoxTab);
            app.Effect3DropDown.Items = {'EQ', 'Reverb', 'Distortion'};
            app.Effect3DropDown.Value = 'Distortion';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = auidoEffectBoxUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end